classdef hearing_threshold < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ChannelButtonGroup          matlab.ui.container.ButtonGroup
        bothButton                  matlab.ui.control.RadioButton
        rightButton                 matlab.ui.control.RadioButton
        leftButton                  matlab.ui.control.RadioButton
        CurrentlevelEditField       matlab.ui.control.NumericEditField
        CurrentlevelEditFieldLabel  matlab.ui.control.Label
        StopButton                  matlab.ui.control.Button
        Experimenttitel             matlab.ui.control.Label
        StartButton                 matlab.ui.control.Button
        NoteTextArea                matlab.ui.control.TextArea
        NoteTextAreaLabel           matlab.ui.control.Label
        Probandenname               matlab.ui.control.EditField
        subjectnameLabel            matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            p = fileparts(mfilename('fullpath'));
            addpath(fullfile(p,'Code','helper_functions')); 
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
%--------------------------------------------------------------------------
% This function is used to measure the hearing threshold of a subject for
% a given signal adaptively. Here, the signal is a sequence of up-chirps 
% with an ISI of 350ms + 50ms Jitter. This signal is adjusted with a gain
% until the hearing threshold is found. The signal can either be presented 
% monaurally (left/right) or diotically. The level of the adjusted signal 
% is returned. If the level matches the hearing threshold, than this level 
% (rms_stim_dB) corresponds to 0 dB SL. Therefore, the CalVal is: 
% CalVal = 0 dB - rms_stim_dB = -rms_stim_dB
%
% The stepsize is 10 dB at first and is decreased to 5,3 and 1 dB when 
% different turning points have been passed.
% 
% output:
%   rms_stim_dB: Level in dB FS (RMS) of presented signal. Digital stimulus
%                level that corresponds to the hearing threshold.
%   
%                Adaptively fitted curve with level values is saved in 
%                results folder.
%
% input:
%   name:    'subjectXX'
%   channel: 0 left
%            1 right
%            [0,1] both
%
%--------------------------------------------------------------------------

% TO DO
% add limitation of gain: reasonable boundaries
% check if trackvolume is applied appropriately
% set initial gain value -> set to dB SPL (peak)

if app.leftButton.Value
    channel = 0;
elseif app.rightButton.Value
    channel = 1;
elseif app.bothButton.Value
    channel = [0,1];
end

name = app.Probandenname.Value;

% clear Axes
cla(app.UIAxes);

%% Settings
%--------------------------------------------------------------------------

% Import Calibration Signal
calibration_signal = 'calibration_up_rmseq.wav';
[calsig, SampFreq] = audioread(fullfile('Code','Stimuli','calibration',calibration_signal));

% Import Settings
Settings   = importdata(fullfile('Code','Settings','Settings.mat'));
ASIODriver = Settings.Driver;

if Settings.SampFreq~=SampFreq
    error("Sampling frequencies don't match (%i~=%i).",Settings.SampFreq,SampFreq)
end

%% SoundmexPro
%--------------------------------------------------------------------------

% Attenuated calibration signal
% Pstim = AttdB + RMSdBStim
% -> scaling = 10^(AttdB/20)

% Start Value for gain (Find out!)
% GaindB = 0;
GaindB = Settings.Calibration.peakSPL.AttdB;

num_tracks = length(channel);
if num_tracks > 1
    tracks    = [0,1];
    plotcolor = {'b','r'};
else
    tracks = 0;
    if channel==0 % left
        GaindB    = GaindB(1);
        plotcolor = {'b'};
    end
    if channel==1 % right
        GaindB    = GaindB(2);
        plotcolor = {'r'};
    end
end

ok = soundmexpro('init', ...            % command name
    'force',      1, ...                % exit internally called before init
    'driver',     ASIODriver, ...
    'samplerate', SampFreq, ...
    'output',     channel, ...
    'input',      [], ...
    'track',      num_tracks, ...
    'autocleardata', 1);            % clear audio data on memory
if ~ok, error('cannot initialize soundmexpro, error calling ''init'' '); end

% Counter for sign changes and runs
maxruns     = 20;
maxturns    = 8;
count_runs  = 0;
count_turns = 0;

% Store data
hearinglevel = []; % count_runs, response and rms_stim_dB

hold(app.UIAxes,'on')

while count_turns < maxturns && count_runs<maxruns

    ok = soundmexpro('cleardata');
    if ~ok, error('error calling ''cleardata'' '); end

    pause(2)

    % Load signal
    %------------
    loopcount = 0; % play sounds once
    ok = soundmexpro('loadmem', ...     % command name
        'data', calsig, ...             % data vector
        'track',tracks, ...
        'loopcount', loopcount);
    if ~ok, error('error calling ''loadmem'''); end

    % Adjust gain
    %------------
    % Signal Level in dB FS (RMS)
    rms_stim_dB = 20*log10(rms(calsig)) + GaindB;
    gain        = 10.^(GaindB/20);

    [ok, ~] = soundmexpro('trackvolume', ...    
        'track', tracks, ...
        'value', gain ...                           % volume values 
        );
    if ~ok, error('error calling ''trackvolume'''); end
    
    % Start SoundMexPro
    %------------------
    ok = soundmexpro('start','length',0); % device is never stopped, zeros are played endlessly 
    if ~ok,  error('error calling ''start''' ); end

    % Response - signal should be played until answer is given (or signal
    % ends - signal is played endlessly)
    %-----------------------------------
    app.CurrentlevelEditField.Value = rms_stim_dB(1);
    answer = questdlg('Can you hear the stimulus sequence?', ...
	         'hearing threshold', ...
	         'Yes','No','Stop','Yes');

    switch answer
        case 'Yes'
            gaindB = gainrule(count_turns,'Ja');
            plotsymbol = '+';
            response   = 1;
        case 'No'
            gaindB = gainrule(count_turns,'Nein');
            plotsymbol = '_';
            response   = 0;
        case 'Stop'
            break % ends execution of while loop -> soundmexpro is closed and results are stored
    end

    ok = soundmexpro('stop'); if ~ok,  error('error calling ''stop'' '); end   

    % Add Gains
    %----------
    GaindB = GaindB + gaindB; 

    % Set counters
    %-------------
    count_runs = count_runs+1;
    
    if count_runs > 1
        if sign(gaindB*gaindB_old)<0 % change in sign -> turning point
            count_turns = count_turns+1;
%             disp(fprintf('Number of turns: %i', count_turns))
        end
    end
    gaindB_old = gaindB;

    % Plot Results
    %-------------
    % save data
    hearinglevel = horzcat(hearinglevel,vertcat(count_runs,response,rms_stim_dB'));
    

    plot(app.UIAxes, hearinglevel(1,count_runs),hearinglevel(3,count_runs),plotsymbol,'MarkerSize',16,'LineWidth',4,'Color',plotcolor{1})
    plot(app.UIAxes, hearinglevel(1,1:count_runs),hearinglevel(3,1:count_runs),'--','Color',plotcolor{1})
    if num_tracks>1
        plot(app.UIAxes, hearinglevel(1,count_runs),hearinglevel(4,count_runs),plotsymbol,'MarkerSize',16,'LineWidth',4,'Color',plotcolor{2})
        plot(app.UIAxes, hearinglevel(1,1:count_runs),hearinglevel(4,1:count_runs),'--','Color',plotcolor{2})
    end

end

ok = soundmexpro('stop'); if ~ok,  error('error calling ''stop'' '); end    
ok = soundmexpro('exit'); if ~ok,  error('error calling ''exit'' '); end

disp('Playback of hearing_threshold.m finished successfully.')

%% Save used Settings
%--------------------------------------------------------------------------

% Ask if you want to save the data
%---------------------------------

answer = questdlg("Should the measurement data be saved?",'Saving','Yes','No','Yes');
switch answer
    case 'Yes'
        p   = fileparts(mfilename('fullpath'));
        pth = fullfile(p,'Results',name);
        if ~exist(pth,'dir')
            mkdir(pth)
        end
        info.Settings      = Settings;
        info.Measdate      = datestr(datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss Z'));
        info.hearinglevel  = hearinglevel;
        info.used_channels = channel;
        
        if isequal(channel,[0,1])
            channelname         = 'both';
            hearinglevel_legend = {'run';'heard (yes:1, no:0)';'level (left) / dB FS (RMS)';'level (right) / dB FS (RMS)'};
        elseif isequal(channel,0)
            channelname         = 'left';
            hearinglevel_legend = {'run';'heard (yes:1, no:0)';'level / dB FS (RMS)'};
        elseif isequal(channel,1)
            channelname         = 'right';
            hearinglevel_legend = {'run';'heard (yes:1, no:0)';'level / dB FS (RMS)'};
        end

        info.hearinglevel_legend = hearinglevel_legend;
        
        % Avoid overwriting of existing file
        inFile  = [name,'_hearinglevel_',channelname,'_info.mat'];
        inPath  = pth;
        outFile = avoidOverwrite(inFile,inPath,2,0);
        save(fullfile(pth,outFile),'info')

        disp('Measurement data was saved successfully.')
    case 'No'     
end


%% extra functions
%--------------------------------------------------------------------------
function gaindB = gainrule(count_turns,answer)
    if count_turns>4
        gaindB = 1; 
    elseif count_turns>3
        gaindB = 2; % 2dB
    elseif count_turns>2 
        gaindB = 5; % 5dB
    else
        gaindB = 10;
    end

    switch answer
        case 'Ja'
            gaindB = -gaindB; % attenuation
        case 'Nein'
                              % amplification
    end
end


        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Wait a LITTLE while for others Scripts to terminate or react
            % appropriate:
            pause(0.05)
            
            % Check if Soundmex is (still) initialized:
            [Success, Initialized] = soundmexpro('initialized');
           
            % first check success of command itself
            if (~Success)
                error('error calling ''initialized''');
            end
            
            % check if device _is_ initialized
            if (Initialized)
                % now exit
                if (1 ~= soundmexpro('exit'))
                    error('error calling ''exit''');
                end
                disp('                SoundmexPRO STOPPED!                   ');
            end
            
            % Show message to the Workspace
            disp('      "Emergency-STOP" of SoundmexPRO used ... !        ');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Presentation')
            ylabel(app.UIAxes, 'Level / dB FS (RMS)')
            zlabel(app.UIAxes, {''; ''})
            app.UIAxes.XLim = [0 20];
            app.UIAxes.XTick = [0 2 4 6 8 10 12 14 16 18 20];
            app.UIAxes.XTickLabel = {'0'; '2'; '4'; '6'; '8'; '10'; '12'; '14'; '16'; '18'; '20'};
            app.UIAxes.ZTick = [];
            app.subjectnameLabel = uilabel(app.UIFigure);
            app.subjectnameLabel.Text = 'Subject name';
            app.Probandenname = uieditfield(app.UIFigure, 'text');
            app.Probandenname.Value = 'subjectXX';
            app.NoteTextAreaLabel = uilabel(app.UIFigure);
            app.NoteTextAreaLabel.Text = 'Note';
            app.NoteTextArea = uitextarea(app.UIFigure);
            app.NoteTextArea.Value = {'Write down the subject code along with the personal code word on a coding list for easy anonymization of the data later. '};
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Experimenttitel = uilabel(app.UIFigure);
            app.Experimenttitel.Text = 'Adaptive measurement of the hearing threshold';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.CurrentlevelEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentlevelEditFieldLabel.Text = 'Current level';
            app.CurrentlevelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChannelButtonGroup = uibuttongroup(app.UIFigure);
            app.ChannelButtonGroup.Title = 'Channel';
            app.leftButton = uiradiobutton(app.ChannelButtonGroup);
            app.leftButton.Text = 'left';
            app.leftButton.Value = true;
            app.rightButton = uiradiobutton(app.ChannelButtonGroup);
            app.rightButton.Text = 'right';
            app.bothButton = uiradiobutton(app.ChannelButtonGroup);
            app.bothButton.Text = 'both';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hearing_threshold

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end