classdef hearing_threshold < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        EarphonesButtonGroup        matlab.ui.container.ButtonGroup
        Sensimetrics_eqButton       matlab.ui.control.RadioButton
        SensimetricsButton          matlab.ui.control.RadioButton
        TIP300Button                matlab.ui.control.RadioButton
        StimulusButtonGroup         matlab.ui.container.ButtonGroup
        DownButton                  matlab.ui.control.RadioButton
        UpButton                    matlab.ui.control.RadioButton
        ClickButton                 matlab.ui.control.RadioButton
        CurrentlevelEditField       matlab.ui.control.NumericEditField
        CurrentlevelEditFieldLabel  matlab.ui.control.Label
        NoteTextArea                matlab.ui.control.TextArea
        Probandenname               matlab.ui.control.EditField
        subjectnameLabel            matlab.ui.control.Label
        ChannelButtonGroup          matlab.ui.container.ButtonGroup
        bothButton                  matlab.ui.control.RadioButton
        rightButton                 matlab.ui.control.RadioButton
        leftButton                  matlab.ui.control.RadioButton
        StopButton                  matlab.ui.control.Button
        Experimenttitel             matlab.ui.control.Label
        StartButton                 matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clearvars
            p = fileparts(mfilename('fullpath'));
            % Add avoidOverwrite function
            addpath(fullfile(p,'additional_functions')); 
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
%--------------------------------------------------------------------------
% Till Habersetzer, 15.11.2024
% Communication Acoustics, CvO University Oldenburg
% till.habersetzer@uol.de
%
% This function measures the hearing threshold adaptively using transient 
% signals (e.g. up-chirps, clicks).
% This function presents a series of transients with a specified inter-stimulus 
% interval (ISI) and jitter, adjusting the gain until the hearing threshold 
% is determined. The signal can be presented to one ear (monaurally) or 
% both ears (diotically).
%
% The initial step size for gain adjustment is 10 dB, which decreases to 
% 5 dB and 2 dB as the procedure progresses through reversal points.
%
%--------------------------------------------------------------------------

% Channel selection
%------------------
if app.leftButton.Value
    channel = 0;
elseif app.rightButton.Value
    channel = 1;
elseif app.bothButton.Value
    channel = [0,1];
end
n_chan = length(channel);

% Stimulus selection
%-------------------
if app.UpButton.Value
    stimulus = 'up';
elseif app.DownButton.Value
    stimulus = 'down';
elseif app.ClickButton.Value
    stimulus = 'click';
end

% Earphones selection
%--------------------
if app.TIP300Button.Value
    earphone = 'tip300';
elseif app.SensimetricsButton.Value
    earphone = 'sensimetrics';
elseif app.Sensimetrics_eqButton.Value
    earphone = 'sensimetrics_eq';
end

% Get subject name for saving
%----------------------------
name = app.Probandenname.Value;

% clear Axes
cla(app.UIAxes);

%% Import Settings
%--------------------------------------------------------------------------

% Import Settings
settings = importdata(fullfile('settings','settings_experiment.mat'));

% Stimulus Settings
switch earphone

    case {'tip300','sensimetrics'}
        audiofile = [stimulus,'.wav'];
        audio_dir = fullfile('audio','tip300');

        % Import audiofile
        [audio, fs] = audioread(fullfile(audio_dir,audiofile));
        % Check if mono
        if size(audio,2)~=1
            error('Mono file expected')
        end
        if n_chan == 2
            audio = [audio,audio];
        end

    case {'sensimetrics_eq'}
        audiofile = [stimulus,'_eq.wav'];
        audio_dir = fullfile('audio','sensimetrics');

        % Import audiofile
        [audio, fs] = audioread(fullfile(audio_dir,audiofile));
        % Check if stereo
        if size(audio,2)~=2
            error('Stereo file expected')
        end
        % Select channel
        if n_chan == 2
            chan_idx = 1:2;
        else
            chan_idx = channel + 1;
        end

        audio = audio(:,chan_idx);
end

cal_val     = settings.calibration.(earphone).cal_val.(stimulus);
start_level = settings.threshold.start_level.(stimulus);

% Check sampling frequency
if settings.samp_freq~=fs
    error("Sampling frequencies don't match (%i~=%i).",settings.samp_freq,fs)
end

% Generate calibration signal, concatenate ~180 stimuli according 
% to stimulus paradgim (3 chirps per sec x 10 ~ 10s)
%--------------------------------------------------------------------------
n_stim = 30; % must be an even number if multiple blocks are concatenated with revered polarity
jitter = settings.jitter;

% Define seed, always the same randomization
rng(13)
jitter = jitter(1) + jitter(2)*rand(1,n_stim) ; 
jitter = round(jitter*fs); % in samples

cal_audio = [];
polarity  = 1;
% Polarity of audio is inversed analogously to experiment
for nidx=1:n_stim
    cal_audio = vertcat(cal_audio,vertcat(polarity*audio,zeros(jitter(nidx),n_chan)));
    polarity  = -polarity;
end
clear audio

%% SoundmexPro
%--------------------------------------------------------------------------

% Apply Calibration
%------------------
% Calibration for transient based on peak-value
level_stim = 20*log10(max(abs(cal_audio),[],1));

% Compute initial gain to achieve start_level
% level_stim + cal_val + gain_dB = start_level
% -> scaling = 10^(gain_dB/20)
gain_dB       = start_level - level_stim - cal_val;
current_level = start_level; % init
gain2add      = 0; % init
level_max     = settings.level_max;

if n_chan > 1
    tracks    = [0,1];
    plotcolor = 'k';
else
    tracks = 0;
    if channel==0 % left
        gain_dB   = gain_dB(1);
        plotcolor = 'b';
    end
    if channel==1 % right
        gain_dB   = gain_dB(2);
        plotcolor = 'r';
    end
end

ok = soundmexpro('init', ...            
    'force',      1, ... % exit internally called before init
    'driver',     settings.asio_driver, ...
    'samplerate', settings.samp_freq, ...
    'output',     channel, ...
    'input',      [], ...
    'track',      n_chan, ...
    'autocleardata', 1); % clear audio data on memory
if ~ok, error('cannot initialize soundmexpro, error calling ''init'' '); end

% Set variables to track state
%-----------------------------
max_runs       = 8; % all trials till reversal point
direction      = -1; % go down
prev_direction = direction; 
correct_count  = 0; % number of correct answers

levels    = []; % store all levels
reversals = []; % store reversal levels for computation of hearing threshold
n_runs    = length(reversals); % A seriesof steps in one direction only is definedas a run
n_trials  = 0;

hold(app.UIAxes,'on')

while n_runs < max_runs

    % Increment trial counter
    n_trials = n_trials +1;

    ok = soundmexpro('cleardata');
    if ~ok, error('error calling ''cleardata'' '); end

    pause(2)

    % Load signal
    %------------
    loopcount = 0; % play sounds once
    ok = soundmexpro('loadmem', ... % command name
        'data', cal_audio, ... % data vector
        'track',tracks, ...
        'loopcount', loopcount);
    if ~ok, error('error calling ''loadmem'''); end

    % Addjust gain
    %-------------
    % Signal Level in (p-p) peSPL
    current_level = current_level + gain2add;
    gain          = 10.^(gain_dB/20);   

    % Check maximum level
    if current_level > level_max
        error("Maximum level exceeded (%i > %i)!",current_level,level_max)
    end

    [ok, ~] = soundmexpro('trackvolume', ...    
        'track', tracks, ...
        'value', gain ...                           % volume values 
        );
    if ~ok, error('error calling ''trackvolume'''); end

    % Start SoundMexPro
    %------------------
    ok = soundmexpro('start','length',0); % device is never stopped, zeros are played endlessly 
    if ~ok,  error('error calling ''start''' ); end

    % Response - signal should be played until answer is given (or signal
    % ends - signal is played endlessly)
    %-----------------------------------
    app.CurrentlevelEditField.Value = current_level;
    app.UIAxes.Title.String = sprintf('Number of reversals = %i', n_runs);

    answer = questdlg('Do you hear the stimulus sequence?', ...
	         'Hearing threshold', ...
	         'Yes','No','Abort','Yes');

    switch answer
        case 'Yes'
            plotsymbol = '+';
            response   = 1;
        case 'No'
            plotsymbol = '_';
            response   = 0;
        case 'Abort'
            break % Ends execution of while loop -> soundmexpro is closed and results are stored
    end

    ok = soundmexpro('stop'); if ~ok,  error('error calling ''stop'' '); end   

    % Apply adaptive procedure and add gain
    %--------------------------------------
    [correct_count, direction, gain2add] = adaptive_staircase(response, correct_count, direction, reversals);

    gain_dB = gain_dB + gain2add; 

    % Check and append for reversals and count
    %-----------------------------------------
    if direction ~= prev_direction
        reversals = [reversals, current_level];
        n_runs    = length(reversals);
        disp(fprintf('Number of reversals: %i', n_runs))
    end

    % Plot Results
    %-------------
    % save data
    levels = horzcat(levels,vertcat(n_trials,response,current_level'));
    
    plot(app.UIAxes, levels(1,n_trials),levels(3,n_trials),plotsymbol,'MarkerSize',16,'LineWidth',4,'Color',plotcolor)
    plot(app.UIAxes, levels(1,1:n_trials),levels(3,1:n_trials),'--','Color',plotcolor)

end

ok = soundmexpro('stop'); if ~ok,  error('error calling ''stop'' '); end    
ok = soundmexpro('exit'); if ~ok,  error('error calling ''exit'' '); end

disp('Measurment of hearing threshold successfully completed.')

% Calculate the threshold as the mean of reversal levels
%-------------------------------------------------------
threshold_estimate = round(mean(reversals(5:end)),1); % take last three estimates
app.UIAxes.Title.String = sprintf('Estimated Threshold: %.1f dB\n', threshold_estimate);

fprintf('Estimated Threshold: %.1f dB\n', threshold_estimate);

%% Save used Settings
%--------------------------------------------------------------------------

% Ask if you want to save the data
%---------------------------------
answer = questdlg("Should the measurement data be saved?",'Save','Yes','No','Yes');
switch answer
    case 'Yes'
        p   = fileparts(mfilename('fullpath'));
        pth = fullfile(p,'results',name);
        if ~exist(pth,'dir')
            mkdir(pth)
        end
        results.settings            = settings;
        results.measdate            = datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss Z');
        results.levels              = levels;
        results.reversals           = reversals;
        results.threshold_estimate  = threshold_estimate;
        results.used_cal_val        = cal_val;
        results.selection.subjectid = name;
        results.selection.channel   = channel;
        results.selection.stimulus  = stimulus;
        results.selection.earphone  = earphone;
        
        if isequal(channel,[0,1])
            channelname         = 'both';
            levels_legend = {'trial';'heard (yes:1, no:0)';'level (left) / dB (p-p) peSPL'; 'level (right) / dB (p-p) peSPL'};
        elseif isequal(channel,0)
            channelname         = 'left';
            levels_legend = {'trial';'heard (yes:1, no:0)';'level / dB (p-p) peSPL'};
        elseif isequal(channel,1)
            channelname         = 'right';
            levels_legend = {'trial';'heard (yes:1, no:0)';'level / dB (p-p) peSPL'};
        end

        results.levels_legend = levels_legend;
        
        % Avoid overwriting of existing file
        in_file  = sprintf('%s_stim-%s_%s_%s_hearinglevel_results.mat',name,stimulus,channelname,earphone); 
        in_path  = pth;
        out_file = avoidOverwrite(in_file,in_path,2,0);
        save(fullfile(pth,out_file),'results')

        disp('Measurement data was saved successfully.')
    case 'No'     
end


%% extra functions
%--------------------------------------------------------------------------
    function [correct_count, direction, gain2add] = adaptive_staircase(response, correct_count, direction, reversals)
    %----------------------------------------------------------------------
    % ADAPTIVE_STAIRCASE Updates parameters in a 2-up, 1-down adaptive staircase procedure.
    %
    % Input:
    %   - response: Boolean (1 for correct, 0 for incorrect).
    %   - correct_count: Integer count of consecutive correct responses.
    %   - direction: Integer (1 for up, -1 for down).
    %
    % Output:
    %   - correct_count: Updated count of consecutive correct responses.
    %   - direction: Updated stimulus adjustment direction.
    %   - reversals: Updated vector of reversal levels.
    %   - gain2add: Gain adjustment for the stimulus level.
    %
    % Procedure:
    %   - Increment `correct_count` for correct responses.
    %   - After 3 correct responses, reset the count, set direction to down,
    %     and calculate `gain2add` using `gainrule`.
    %   - For incorrect responses, reset `correct_count`, set direction to up,
    %     and calculate `gain2add`.
    %
    %----------------------------------------------------------------------

        if response % correct answer
            correct_count = correct_count + 1;
            if correct_count == 2 % Go down after 3 correct responses
    
                % Reset counter
                correct_count = 0;
                % Remember direction for reversals
                % Go down
                direction = -1;
                % Adjust gain
                gain2add = gainrule(reversals, direction);
    
            else % Apply no gain
                gain2add = 0;
            end
    
        else % Incorrect answer
            correct_count = 0;  % Reset correct counter
            direction = 1;
            gain2add  = gainrule(reversals, direction);
        end

    end

    function gain2add = gainrule(reversals,direction)
    %----------------------------------------------------------------------
    % GAINRULE Determines the gain adjustment for the stimulus level.
    %
    % Input:
    %   - reversals: Vector of reversal levels.
    %   - direction: Integer indicating adjustment direction (-1 or 1).
    %
    % Output:
    %   - gain2add: Gain adjustment (positive or negative) based on the number
    %     of reversals and direction.
    %
    % Gain Adjustment Rules:
    %   - <2 reversals: 10 dB (large step)
    %   - 2-3 reversals: 5 dB (medium step)
    %   - ≥4 reversals: 2 dB (small step)
    %----------------------------------------------------------------------

        n_reversals = length(reversals);
        if n_reversals < 2
            gain2add = 10;
        elseif n_reversals == 2 || n_reversals == 3
            gain2add = 5;    
        elseif n_reversals >= 4
            gain2add = 2;
        end

        gain2add = direction*gain2add;

    end

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Wait a LITTLE while for others Scripts to terminate or react
            % appropriate:
            pause(0.05)
            
            % Check if Soundmex is (still) initialized:
            [ok, initialized] = soundmexpro('initialized');
           
            % first check success of command itself
            if (~ok)
                error('error calling ''initialized''');
            end
            
            % check if device _is_ initialized
            if (initialized)
                % now exit
                if (1 ~= soundmexpro('exit'))
                    error('error calling ''exit''');
                end
                disp('                SoundmexPRO STOPPED!                   ');
            end
            
            % Show message to the Workspace
            disp('      "Emergency-STOP" of SoundmexPRO used ... !        ');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Trial Number')
            ylabel(app.UIAxes, 'Level / dB (p-p) peSPL')
            zlabel(app.UIAxes, {''; ''})
            app.UIAxes.XLim = [0 26];
            app.UIAxes.XTick = [0 2 4 6 8 10 12 14 16 18 20 22 24 26];
            app.UIAxes.XTickLabel = {'0'; '2'; '4'; '6'; '8'; '10'; '12'; '14'; '16'; '18'; '20'; '22'; '24'; '26'};
            app.UIAxes.ZTick = [];
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Experimenttitel = uilabel(app.UIFigure);
            app.Experimenttitel.Text = 'Adaptive measurement of the hearing threshold';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ChannelButtonGroup = uibuttongroup(app.UIFigure);
            app.ChannelButtonGroup.Title = 'Channel';
            app.leftButton = uiradiobutton(app.ChannelButtonGroup);
            app.leftButton.Text = 'left';
            app.leftButton.Value = true;
            app.rightButton = uiradiobutton(app.ChannelButtonGroup);
            app.rightButton.Text = 'right';
            app.bothButton = uiradiobutton(app.ChannelButtonGroup);
            app.bothButton.Text = 'both';
            app.subjectnameLabel = uilabel(app.UIFigure);
            app.subjectnameLabel.Text = 'Subject name';
            app.Probandenname = uieditfield(app.UIFigure, 'text');
            app.Probandenname.Value = 'sub-XX';
            app.NoteTextArea = uitextarea(app.UIFigure);
            app.NoteTextArea.Value = {'Setup needs to be calibrated in dB (p-p) peSPL'};
            app.CurrentlevelEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentlevelEditFieldLabel.Text = 'Current level';
            app.CurrentlevelEditField = uieditfield(app.UIFigure, 'numeric');
            app.StimulusButtonGroup = uibuttongroup(app.UIFigure);
            app.StimulusButtonGroup.Title = 'Stimulus';
            app.ClickButton = uiradiobutton(app.StimulusButtonGroup);
            app.ClickButton.Text = 'Click';
            app.ClickButton.Value = true;
            app.UpButton = uiradiobutton(app.StimulusButtonGroup);
            app.UpButton.Text = 'Up';
            app.DownButton = uiradiobutton(app.StimulusButtonGroup);
            app.DownButton.Text = 'Down';
            app.EarphonesButtonGroup = uibuttongroup(app.UIFigure);
            app.EarphonesButtonGroup.Title = 'Earphones';
            app.TIP300Button = uiradiobutton(app.EarphonesButtonGroup);
            app.TIP300Button.Text = 'TIP300';
            app.TIP300Button.Value = true;
            app.SensimetricsButton = uiradiobutton(app.EarphonesButtonGroup);
            app.SensimetricsButton.Text = 'Sensimetrics';
            app.Sensimetrics_eqButton = uiradiobutton(app.EarphonesButtonGroup);
            app.Sensimetrics_eqButton.Text = 'Sensimetrics_eq';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hearing_threshold

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end