classdef practical_meg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HearingThresholdEditField       matlab.ui.control.EditField
        HearingThresholdEditFieldLabel  matlab.ui.control.Label
        StimulusButtonGroup             matlab.ui.container.ButtonGroup
        DownButton                      matlab.ui.control.RadioButton
        UpButton                        matlab.ui.control.RadioButton
        ClickButton                     matlab.ui.control.RadioButton
        EarphonesButtonGroup            matlab.ui.container.ButtonGroup
        Sensimetrics_eqButton           matlab.ui.control.RadioButton
        SensimetricsButton              matlab.ui.control.RadioButton
        TIP300Button                    matlab.ui.control.RadioButton
        NoteTextArea                    matlab.ui.control.TextArea
        NoteTextAreaLabel               matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        FixationcrossSwitch             matlab.ui.control.Switch
        FixationcrossSwitchLabel        matlab.ui.control.Label
        StopstopsSoundMexProButton      matlab.ui.control.Button
        Experimenttitel                 matlab.ui.control.Label
        Probandenname                   matlab.ui.control.EditField
        subjectnameLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
         hfig     = []; % figure for fixation cross
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clearvars
            p = fileparts(mfilename('fullpath'));
            addpath(fullfile(p,'EEGTriggerTB')); % for trigger, is not added in experimental scripts
            addpath(fullfile(p,'experiment_scripts')); 
            addpath(fullfile(p,'fixation_cross')); 
            addpath(fullfile(p,'additional_functions'));
        end

        % Value changed function: FixationcrossSwitch
        function FixationcrossSwitchValueChanged(app, event)
            value = app.FixationcrossSwitch.Value;
            switch value
                case 'On'
                    app.hfig = display_fixation_cross;
                    
                case 'Off'
                    close(app.hfig)
            end
        end

        % Button pushed function: StopstopsSoundMexProButton
        function StopstopsSoundMexProButtonPushed(app, event)
            % Wait a LITTLE while for others Scripts to terminate or react
            % appropriate:
            pause(0.05)
            
            % Check if Soundmex is (still) initialized:
            [ok, initialized] = soundmexpro('initialized');
           
            % first check success of command itself
            if (~ok)
                error('error calling ''initialized''');
            end
            
            % check if device _is_ initialized
            if (initialized)
                % now exit
                if (1 ~= soundmexpro('exit'))
                    error('error calling ''exit''');
                end
                disp('                SoundmexPRO STOPPED!                   ');
            end
            
            % Show message to the Workspace
            disp('      "Emergency-STOP" of SoundmexPRO used ... !        ');
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            % Stimulus selection
            %-------------------
            if app.UpButton.Value
                stimulus = 'up';
            elseif app.DownButton.Value
                stimulus = 'down';
            elseif app.ClickButton.Value
                stimulus = 'click';
            end
            
            % Earphones selection
            %--------------------
            if app.TIP300Button.Value
                earphone = 'tip300';
            elseif app.SensimetricsButton.Value
                earphone = 'sensimetrics';
            elseif app.Sensimetrics_eqButton.Value
                earphone = 'sensimetrics_eq';
            end

            % Import Settings
            settings = importdata(fullfile('settings','settings_experiment.mat'));

            % Show measured hearing threshold
            %--------------------------------
            app.HearingThresholdEditField.Value = num2str(settings.threshold.(earphone).(stimulus));

            % Play script
            %------------
            experiment_script(app.UIFigure,settings,app.Probandenname.Value,stimulus,earphone)
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.subjectnameLabel = uilabel(app.UIFigure);
            app.subjectnameLabel.Text = 'Subject name';
            app.Probandenname = uieditfield(app.UIFigure, 'text');
            app.Probandenname.Value = 'sub-XX';
            app.Experimenttitel = uilabel(app.UIFigure);
            app.Experimenttitel.Text = 'MEG Practical';
            app.StopstopsSoundMexProButton = uibutton(app.UIFigure, 'push');
            app.StopstopsSoundMexProButton.ButtonPushedFcn = createCallbackFcn(app, @StopstopsSoundMexProButtonPushed, true);
            app.StopstopsSoundMexProButton.Text = {'Stop '; '(stops SoundMexPro)'};
            app.FixationcrossSwitchLabel = uilabel(app.UIFigure);
            app.FixationcrossSwitchLabel.Text = 'Fixation cross';
            app.FixationcrossSwitch = uiswitch(app.UIFigure, 'slider');
            app.FixationcrossSwitch.ValueChangedFcn = createCallbackFcn(app, @FixationcrossSwitchValueChanged, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.NoteTextAreaLabel = uilabel(app.UIFigure);
            app.NoteTextAreaLabel.Text = 'Note';
            app.NoteTextArea = uitextarea(app.UIFigure);
            app.NoteTextArea.Value = {'1.) Write down the subject code along with the personal code word on a coding list for easy anonymization of the data later. '; '2.) Change the calibration values in the settings for each subject before measurement to calibrate in dB SL.'};
            app.EarphonesButtonGroup = uibuttongroup(app.UIFigure);
            app.EarphonesButtonGroup.Title = 'Earphones';
            app.TIP300Button = uiradiobutton(app.EarphonesButtonGroup);
            app.TIP300Button.Text = 'TIP300';
            app.TIP300Button.Value = true;
            app.SensimetricsButton = uiradiobutton(app.EarphonesButtonGroup);
            app.SensimetricsButton.Text = 'Sensimetrics';
            app.Sensimetrics_eqButton = uiradiobutton(app.EarphonesButtonGroup);
            app.Sensimetrics_eqButton.Text = 'Sensimetrics_eq';
            app.StimulusButtonGroup = uibuttongroup(app.UIFigure);
            app.StimulusButtonGroup.Title = 'Stimulus';
            app.ClickButton = uiradiobutton(app.StimulusButtonGroup);
            app.ClickButton.Text = 'Click';
            app.ClickButton.Value = true;
            app.UpButton = uiradiobutton(app.StimulusButtonGroup);
            app.UpButton.Text = 'Up';
            app.DownButton = uiradiobutton(app.StimulusButtonGroup);
            app.DownButton.Text = 'Down';
            app.HearingThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.HearingThresholdEditFieldLabel.Text = 'Hearing Threshold';
            app.HearingThresholdEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = practical_meg

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end